classdef Animation_plot < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        filenameEditField       matlab.ui.control.EditField
        filenameEditFieldLabel  matlab.ui.control.Label
        filmCheckBox            matlab.ui.control.CheckBox
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Roll, Pitch, Yaw, AOA, AOS)
            addpath('./animation/')
            % Select and load 3D model (see "generate_mat_from_stl.m" for examples)
            load('animation\rocket_model.mat', 'Model3D');
            % Open the video output if we are recording the movie
            if  app.filmCheckBox == 1
                aviobj = VideoWriter(app.filenameEditField, 'MPEG-4');
                aviobj.Quality = 100;  % movie quality
                aviobj.FrameRate = 1/frame_time;
                open(aviobj);
            end
            % Get maximum dimension including all the aircraft's parts
            AC_DIMENSION = max(max(sqrt(sum(Model3D.Aircraft(1).stl_data.vertices.^2, 2))));
            for i=1:length(Model3D.Control)
                AC_DIMENSION = max(AC_DIMENSION, max(max(sqrt(sum(Model3D.Control(i).stl_data.vertices.^2, 2)))));
            end
            
            %% Initialize the figure
%             hf = figure;
%             AX = axes('position',[0.0 0.0 1 1]);
%             axis off
%             scrsz = get(0, 'ScreenSize');
%             set(gcf, 'Position',[scrsz(3)/40 scrsz(4)/12 scrsz(3)/2*1.0 scrsz(3)/2.2*1.0], 'Visible', 'on');
%             set(AX, 'color', 'none');
%             axis('equal')
%             hold on;
%             cameratoolbar('Show')

            % Initializate transformation group handles
            % -------------------------------------------------------------------------
            % Aircraft transformation group handle
            AV_hg         = hgtransform;
            % controls_deflection_deg transformation group handles
            CONT_hg       = zeros(1,length(Model3D.Control));
            for i=1:length(Model3D.Control)
                CONT_hg(i) = hgtransform('Parent', AV_hg, 'tag', Model3D.Control(i).label);
            end
            % Circles around the aircraft transformation group handles
            euler_hgt(1)  = hgtransform('Parent',           AX, 'tag', 'OriginAxes');
            euler_hgt(2)  = hgtransform('Parent', euler_hgt(1), 'tag', 'roll_disc');
            euler_hgt(3)  = hgtransform('Parent', euler_hgt(1), 'tag', 'pitch_disc');
            euler_hgt(4)  = hgtransform('Parent', euler_hgt(1), 'tag', 'heading_disc');
            euler_hgt(5)  = hgtransform('Parent', euler_hgt(2), 'tag', 'roll_line');
            euler_hgt(6)  = hgtransform('Parent', euler_hgt(3), 'tag', 'pitch_line');
            euler_hgt(7)  = hgtransform('Parent', euler_hgt(4), 'tag', 'heading_line');
            % Plot objects
            % -------------------------------------------------------------------------
            % Plot airframe
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.filmCheckBox = uicheckbox(app.UIFigure);
            app.filmCheckBox.Text = 'film';
            app.filenameEditFieldLabel = uilabel(app.UIFigure);
            app.filenameEditFieldLabel.Text = 'file name';
            app.filenameEditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Animation_plot(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end