classdef testapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        EditField       matlab.ui.control.EditField
        EditFieldLabel  matlab.ui.control.Label
        Button          matlab.ui.control.Button
        UIAxes_2        matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            table = readtable('Flight_data\20231102_DynamicTest_01.xlsm');
            time = table.UTC;
            str2double(time)
%             time = sprintf('%s',datetime("now"));
%             app.EditField.Value = time(1);
            a = animatedline(app.UIAxes,'MaximumNumPoints',1000,'Color','r');
            view(app.UIAxes,3);
            a2 = animatedline(app.UIAxes,'MaximumNumPoints',1000,'Color','b');
            view(app.UIAxes_2,3);
            for t = 1:inf
            addpoints(a,t,sin(0.1*t),cos(0.1*t))
%             drawnow
            addpoints(a2,t,sin(0.2*t),cos(0.1*t))
            drawnow limitrate
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Plot')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Plot')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = 'Edit Field';
            app.EditField = uieditfield(app.UIFigure, 'text');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = testapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end