classdef Mass_distribution < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        CGpositionfromnosePanel     matlab.ui.container.Panel
        CCCGEditField               matlab.ui.control.NumericEditField
        mLabel_7                    matlab.ui.control.Label
        TailCGEditField             matlab.ui.control.NumericEditField
        mLabel_6                    matlab.ui.control.Label
        ConnectCGEditField          matlab.ui.control.NumericEditField
        mLabel_5                    matlab.ui.control.Label
        N2OCGEditField              matlab.ui.control.NumericEditField
        mLabel_4                    matlab.ui.control.Label
        AvionicsCGEditField         matlab.ui.control.NumericEditField
        mLabel_3                    matlab.ui.control.Label
        CanardCGEditField           matlab.ui.control.NumericEditField
        mLabel_2                    matlab.ui.control.Label
        mLabel                      matlab.ui.control.Label
        PayloadCGEditField          matlab.ui.control.NumericEditField
        CanardLabel_2               matlab.ui.control.Label
        CCCombustionchamberLabel_2  matlab.ui.control.Label
        ConnectionLabel_2           matlab.ui.control.Label
        TailLabel_2                 matlab.ui.control.Label
        CCLabel_2                   matlab.ui.control.Label
        N2OLabel_2                  matlab.ui.control.Label
        AvionicsLabel_2             matlab.ui.control.Label
        PayloadLabel_2              matlab.ui.control.Label
        Image2                      matlab.ui.control.Image
        CloseButton                 matlab.ui.control.Button
        SaveButton                  matlab.ui.control.Button
        WeightPanel                 matlab.ui.container.Panel
        FinalmassLabel_3            matlab.ui.control.Label
        N2OfinmassEditField         matlab.ui.control.NumericEditField
        kgLabel_17                  matlab.ui.control.Label
        InitialmassLabel_3          matlab.ui.control.Label
        FinalmassLabel              matlab.ui.control.Label
        CCfinmassEditField          matlab.ui.control.NumericEditField
        kgLabel_8                   matlab.ui.control.Label
        InitialmassLabel            matlab.ui.control.Label
        CCinimassEditField          matlab.ui.control.NumericEditField
        kgLabel_7                   matlab.ui.control.Label
        ConnectmassEditField        matlab.ui.control.NumericEditField
        kgLabel_6                   matlab.ui.control.Label
        N2OinimassEditField         matlab.ui.control.NumericEditField
        kgLabel_5                   matlab.ui.control.Label
        AvionicsmassEditField       matlab.ui.control.NumericEditField
        kgLabel_4                   matlab.ui.control.Label
        CanardmassEditField         matlab.ui.control.NumericEditField
        kgLabel_3                   matlab.ui.control.Label
        PayloadmassEditField        matlab.ui.control.NumericEditField
        kgLabel_2                   matlab.ui.control.Label
        TaildmassEditField          matlab.ui.control.NumericEditField
        kgLabel                     matlab.ui.control.Label
        CanardLabel                 matlab.ui.control.Label
        CCCombustionchamberLabel    matlab.ui.control.Label
        ConnectionLabel             matlab.ui.control.Label
        TailLabel                   matlab.ui.control.Label
        CCLabel                     matlab.ui.control.Label
        N2OLabel                    matlab.ui.control.Label
        AvionicsLabel               matlab.ui.control.Label
        PayloadLabel                matlab.ui.control.Label
        Image                       matlab.ui.control.Image
    end

    
    properties (Access = private)
        sel_config % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, Modify_config)
            app.sel_config = Modify_config;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            switch app.sel_config
                case 1
                    fid=fopen('Config_ver1.m','W');
                case 2
                    fid=fopen('Config_ver2.m','W');
            end
            fprintf(fid,['function y = Rocket_property(U)\n' ...
                'thr_cmd = U(1);\n' ...
                't = U(2);\n' ...
                '\n']);
            %% initial conditions 
            %fixed parts:mass(kg),CG(m)
            fprintf(fid,['Nose_tube_mass = 1.72;\n' ...
                'payload1_mass = %f;\n' ...
                'Canard_mass = %f;\n'...
                'Avionic_tube_mass = 2.94;\n' ...
                'avi_act_rec_mass = %f;\n' ...
                'N2O_tank_mass = 18;\n' ...                                          %all = 18
                'Connect_mass = 0.88;\n' ...
                'payload3_mass = %f;\n' ...
                'combus_mass = 29.5;\n' ...                                          %origin,lightening = 39.7 , highreg = 29.5
                'fin_mass = %f;\n' ...
                '\n']...
                ,app.PayloadmassEditField.Value,app.CanardmassEditField.Value,app.AvionicsmassEditField.Value, ...
                app.ConnectmassEditField.Value,app.TaildmassEditField.Value);
            fprintf(fid,['Nose_x = 0.54;\n' ...
                'payload1_x = %f;\n' ...
                'Canard_x = %f;\n' ...
                'Avionic_x = 1.37;\n' ...
                'payload2_x = %f;\n' ...
                'N2O_tank_x = 3.136;\n' ...
                'Connect_x = 4.556;\n' ...
                'payload3_x = %f;\n' ...
                'combus_x = 5.031;\n' ...                                              %origin,lightening = 5.556  ,highreg = 5.031
                'fin_x = %f;\n' ...
                '\n']...
                ,app.PayloadCGEditField.Value,app.CanardCGEditField.Value,app.AvionicsCGEditField.Value, ...
                app.ConnectCGEditField.Value,app.TailCGEditField.Value);
            %N2O:mass(kg),CG(m)
            fprintf(fid,['N2O_long = 2.35;\n' ...
                'N2O_massi = %f;\n' ...                                                 %all = 91
                'N2O_massf = %f;\n' ...                                                %all = 17
                'N2O_xi = 0.866+1+2.54-N2O_long/2;\n' ...
                '\n'] ...
                ,app.N2OinimassEditField.Value,app.N2OfinmassEditField.Value);
            %fuel(kg),CG(m)
            fprintf(fid,['Fuel_massi = %f;\n' ...                                       %origin = 60.9 ,lightening = 36.5 , highreg = 18
                'Fuel_massf = %f;\n' ...                                             %origin = 48.6 ,lightening = 24.3 , highreg = 5.7
                'Fuel_x0 = 0.866+1+2.54+0.3+1.7/2;\n' ...
                '\n'] ...
                ,app.CCinimassEditField.Value,app.CCfinmassEditField.Value);
            %every part's inertia about each CG
            fprintf(fid,['fin_y = 0.21292;\n' ...                                  %%%%
                'Nose_I = [0.086672 0.086672 0.020317];\n' ...
                'payload1_I = [0.002527 0.002527 0.003200];\n' ...
                'Avionic_I = [0.270551 0.270551 0.050867];\n' ...
                'payload2_I = [0.332395 0.332395 0.324958];\n' ...
                'N2O_tank_I = [10.754864 10.754864 0.302346];\n' ...
                'N2O_Ii = [42.266237 42.266237 0.769001];\n' ...                    %all = [42.266237 42.266237 0.769001]; 
                'N2O_If = [0.344861 0.344861 0.143656];\n' ...                      %all = [0.344861 0.344861 0.143656];
                'if t<15\n' ...                                                     %all = [-0.0067*(t+1)^3 + 0.3913*(t+1)^2 - 7.6138*(t+1) + 49.556 , -0.0067*(t+1)^3 + 0.3913*(t+1)^2 - 7.6138*(t+1) + 49.556 ,  -0.0417*(t+1) + 0.8109];
                '   N2O_I = [-0.0067*(t+1)^3 + 0.3913*(t+1)^2 - 7.6138*(t+1) + 49.556,' ...
                '           -0.0067*(t+1)^3 + 0.3913*(t+1)^2 - 7.6138*(t+1) + 49.556,' ...
                '           -0.0417*(t+1) + 0.8109];\n' ...
                'else\n' ...
                '   N2O_I = N2O_If;\n' ...
                'end\n' ...
                'fin_I = [0.036099 0.034037 0.002064];\n' ...
                'Connect_I = [0.014248 0.014248 0.015260];\n' ...
                'payload3_I = [0.022439 0.022439 0.016683];\n' ...
                'combus_I = [11.166158 11.166158 0.642622];\n' ...
                'Fuel_Ii = [0.7129 0.7129 0.204];\n' ...                       %origin = [14.77 14.77 0.61];      lightening = [8.89 8.89 0.453];     highreg = [0.7129 0.7129 0.204];
                'Fuel_If = [0.2276 0.2276 0.065];\n' ...                        %origin = [11.81 11.81 0.488];    lightening = [5.917 5.917 0.3016];     highreg = [0.2276 0.2276 0.065];
                'if t <15\n' ...
                '   Fuel_I = [-0.0324*(t+1)+0.7452,-0.0324*(t+1)+0.7452,-0.0093*(t+1)+0.2132];\n' ...
                'else\n' ...
                '   Fuel_I = Fuel_If;\n' ...
                'end\n']);
            %calculate fixed mass,CG
            fprintf(fid,['fixed_mass = Nose_tube_mass+payload1_mass+Avionic_tube_mass+...\n' ...
                '   avi_act_rec_mass+N2O_tank_mass+Connect_mass+payload3_mass+...\n' ...
                '   combus_mass+4*fin_mass;\n' ...
                'fixed_x = (Nose_tube_mass*Nose_x+payload1_mass*payload1_x+...\n' ...
                '   Avionic_tube_mass*Avionic_x+avi_act_rec_mass*payload2_x+...\n' ...
                '   N2O_tank_mass*N2O_tank_x+Connect_mass*Connect_x+...\n' ...
                '   payload3_mass*payload3_x+combus_mass*combus_x+...\n' ...
                '   4*fin_mass*fin_x)/fixed_mass;\n']);
            %calculate N2O mass,CG
            fprintf(fid,['if thr_cmd==1\n' ...
                '   N2O_mass = N2O_massi - (N2O_massi-N2O_massf)/15*t;\n' ...
                'else\n' ...
                '   N2O_mass = N2O_massf;\n' ...
                'end\n' ...
                'N2O_x = N2O_xi + N2O_long/2 - (N2O_long/N2O_massi.*N2O_mass)/2;\n']);
            %calculate fuel mass,CG
            fprintf(fid,['if thr_cmd==1\n' ...
                '   Fuel_mass = Fuel_massi - (Fuel_massi-Fuel_massf)/15*t;\n' ...
                'else\n' ...
                '   Fuel_mass = Fuel_massf;\n' ...
                'end\n' ...
                'Fuel_x = Fuel_x0;\n' ...
                '\n']);
            %% calculate total mass
            fprintf(fid,['m = fixed_mass+N2O_mass+Fuel_mass;\n' ...
                'CG = (fixed_mass*fixed_x + N2O_mass*N2O_x + Fuel_mass*Fuel_x)/m;\n' ...
                '\n']);
            %% calculate I
            fprintf(fid,['Ixx = Nose_I(3)+payload1_I(3)+Avionic_I(3)+payload2_I(3)+...\n' ...
                '  N2O_tank_I(3)+N2O_I(3)+Connect_I(3)+payload3_I(3)+...\n' ...
                '   combus_I(3)+Fuel_I(3)+4*(fin_I(3)+fin_mass*fin_y^2);\n' ...
                'Iyy = Nose_I(1)+Nose_tube_mass*(CG-Nose_x)^2+payload1_I(1)+...\n' ...
                '   payload1_mass*(CG-payload1_x)^2+Avionic_I(1)+...\n' ...
                '   Avionic_tube_mass*(CG-Avionic_x)^2+payload2_I(1)+...\n' ...
                '   avi_act_rec_mass*(CG-payload2_x)^2+N2O_tank_I(1)+...\n' ...
                '   N2O_tank_mass*(CG-N2O_tank_x)^2+N2O_I(1)+...\n' ...
                '   N2O_mass*(CG-N2O_x)^2+Connect_I(1)+...\n' ...
                '   Connect_mass*(CG-Connect_x)^2+payload3_I(1)+...\n' ...
                '   payload3_mass*(CG-payload3_x)^2+combus_I(1)+...\n' ...
                '   combus_mass*(CG-combus_x)^2+4*(fin_I(1)+...\n' ...
                '   fin_mass*(CG-fin_x)^2)+Fuel_I(1)+...\n' ...
                '   Fuel_mass*(CG-Fuel_x)^2;\n' ...
                'Izz = Iyy;\n' ...
                'I = [Ixx;Iyy;Izz];\n' ...
                '\n']);
            %% thrust calculate
            fprintf(fid,['if t<3.3\n' ...
                '   thrust = 1000/3.3*t;\n' ...%kgf
                'else if t<5\n' ...
                '   thrust = 1000 + (1275-1000)/(5-3.3)*(t-3.3);\n' ...
                'else if t<15\n' ...
                '   thrust = 1275 + (1240-1275)/(15-5)*(t-5);\n' ...
                'else\n' ...
                '   thrust = 0;\n' ...
                'end\n' ...
                'end\n' ...
                'end\n']);
            %% output
            fprintf(fid,['y = [m;CG;I;thrust];\n' ...
                'end']);

            fclose(fid);
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            delete(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.WeightPanel = uipanel(app.UIFigure);
            app.WeightPanel.Title = 'Weight';
            app.Image = uiimage(app.WeightPanel);
            app.Image.ImageSource = 'rocket_mass.png';
            app.PayloadLabel = uilabel(app.WeightPanel);
            app.PayloadLabel.Text = 'Payload';
            app.AvionicsLabel = uilabel(app.WeightPanel);
            app.AvionicsLabel.Text = 'Avionics';
            app.N2OLabel = uilabel(app.WeightPanel);
            app.N2OLabel.Text = 'N2O';
            app.CCLabel = uilabel(app.WeightPanel);
            app.CCLabel.Text = 'C.C.';
            app.TailLabel = uilabel(app.WeightPanel);
            app.TailLabel.Text = 'Tail';
            app.ConnectionLabel = uilabel(app.WeightPanel);
            app.ConnectionLabel.Text = 'Connection';
            app.CCCombustionchamberLabel = uilabel(app.WeightPanel);
            app.CCCombustionchamberLabel.Text = '*C.C.=Combustion chamber';
            app.CanardLabel = uilabel(app.WeightPanel);
            app.CanardLabel.Text = 'Canard';
            app.kgLabel = uilabel(app.WeightPanel);
            app.kgLabel.Text = 'kg';
            app.TaildmassEditField = uieditfield(app.WeightPanel, 'numeric');
            app.TaildmassEditField.Value = 2.5;
            app.kgLabel_2 = uilabel(app.WeightPanel);
            app.kgLabel_2.Text = 'kg';
            app.PayloadmassEditField = uieditfield(app.WeightPanel, 'numeric');
            app.PayloadmassEditField.Value = 10;
            app.kgLabel_3 = uilabel(app.WeightPanel);
            app.kgLabel_3.Text = 'kg';
            app.CanardmassEditField = uieditfield(app.WeightPanel, 'numeric');
            app.CanardmassEditField.Value = 1;
            app.kgLabel_4 = uilabel(app.WeightPanel);
            app.kgLabel_4.Text = 'kg';
            app.AvionicsmassEditField = uieditfield(app.WeightPanel, 'numeric');
            app.AvionicsmassEditField.Value = 20;
            app.kgLabel_5 = uilabel(app.WeightPanel);
            app.kgLabel_5.Text = 'kg';
            app.N2OinimassEditField = uieditfield(app.WeightPanel, 'numeric');
            app.N2OinimassEditField.Value = 91;
            app.kgLabel_6 = uilabel(app.WeightPanel);
            app.kgLabel_6.Text = 'kg';
            app.ConnectmassEditField = uieditfield(app.WeightPanel, 'numeric');
            app.ConnectmassEditField.Value = 10;
            app.kgLabel_7 = uilabel(app.WeightPanel);
            app.kgLabel_7.Text = 'kg';
            app.CCinimassEditField = uieditfield(app.WeightPanel, 'numeric');
            app.CCinimassEditField.Value = 18;
            app.InitialmassLabel = uilabel(app.WeightPanel);
            app.InitialmassLabel.Text = 'Initial mass';
            app.kgLabel_8 = uilabel(app.WeightPanel);
            app.kgLabel_8.Text = 'kg';
            app.CCfinmassEditField = uieditfield(app.WeightPanel, 'numeric');
            app.CCfinmassEditField.Value = 5.7;
            app.FinalmassLabel = uilabel(app.WeightPanel);
            app.FinalmassLabel.Text = 'Final mass';
            app.InitialmassLabel_3 = uilabel(app.WeightPanel);
            app.InitialmassLabel_3.Text = 'Initial mass';
            app.kgLabel_17 = uilabel(app.WeightPanel);
            app.kgLabel_17.Text = 'kg';
            app.N2OfinmassEditField = uieditfield(app.WeightPanel, 'numeric');
            app.N2OfinmassEditField.Value = 17;
            app.FinalmassLabel_3 = uilabel(app.WeightPanel);
            app.FinalmassLabel_3.Text = 'Final mass';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.CloseButton = uibutton(app.UIFigure, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            app.CGpositionfromnosePanel = uipanel(app.UIFigure);
            app.CGpositionfromnosePanel.Title = 'CG position from nose';
            app.Image2 = uiimage(app.CGpositionfromnosePanel);
            app.Image2.ImageSource = 'rocket_CG.png';
            app.PayloadLabel_2 = uilabel(app.CGpositionfromnosePanel);
            app.PayloadLabel_2.Text = 'Payload';
            app.AvionicsLabel_2 = uilabel(app.CGpositionfromnosePanel);
            app.AvionicsLabel_2.Text = 'Avionics';
            app.N2OLabel_2 = uilabel(app.CGpositionfromnosePanel);
            app.N2OLabel_2.Text = 'N2O';
            app.CCLabel_2 = uilabel(app.CGpositionfromnosePanel);
            app.CCLabel_2.Text = 'C.C.';
            app.TailLabel_2 = uilabel(app.CGpositionfromnosePanel);
            app.TailLabel_2.Text = 'Tail';
            app.ConnectionLabel_2 = uilabel(app.CGpositionfromnosePanel);
            app.ConnectionLabel_2.Text = 'Connection';
            app.CCCombustionchamberLabel_2 = uilabel(app.CGpositionfromnosePanel);
            app.CCCombustionchamberLabel_2.Text = '*C.C.=Combustion chamber';
            app.CanardLabel_2 = uilabel(app.CGpositionfromnosePanel);
            app.CanardLabel_2.Text = 'Canard';
            app.PayloadCGEditField = uieditfield(app.CGpositionfromnosePanel, 'numeric');
            app.PayloadCGEditField.Value = 0.54;
            app.mLabel = uilabel(app.CGpositionfromnosePanel);
            app.mLabel.Text = 'm';
            app.mLabel_2 = uilabel(app.CGpositionfromnosePanel);
            app.mLabel_2.Text = 'm';
            app.CanardCGEditField = uieditfield(app.CGpositionfromnosePanel, 'numeric');
            app.CanardCGEditField.Value = 1.03;
            app.mLabel_3 = uilabel(app.CGpositionfromnosePanel);
            app.mLabel_3.Text = 'm';
            app.AvionicsCGEditField = uieditfield(app.CGpositionfromnosePanel, 'numeric');
            app.AvionicsCGEditField.Value = 1.37;
            app.mLabel_4 = uilabel(app.CGpositionfromnosePanel);
            app.mLabel_4.Text = 'm';
            app.N2OCGEditField = uieditfield(app.CGpositionfromnosePanel, 'numeric');
            app.N2OCGEditField.Value = 3.14;
            app.mLabel_5 = uilabel(app.CGpositionfromnosePanel);
            app.mLabel_5.Text = 'm';
            app.ConnectCGEditField = uieditfield(app.CGpositionfromnosePanel, 'numeric');
            app.ConnectCGEditField.Value = 4.56;
            app.mLabel_6 = uilabel(app.CGpositionfromnosePanel);
            app.mLabel_6.Text = 'm';
            app.TailCGEditField = uieditfield(app.CGpositionfromnosePanel, 'numeric');
            app.TailCGEditField.Value = 6.04;
            app.mLabel_7 = uilabel(app.CGpositionfromnosePanel);
            app.mLabel_7.Text = 'm';
            app.CCCGEditField = uieditfield(app.CGpositionfromnosePanel, 'numeric');
            app.CCCGEditField.Value = 5.03;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Mass_distribution(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end